classdef gausian_beam_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        BeamSummaryTable      matlab.ui.control.Table
        W0mEditField          matlab.ui.control.NumericEditField
        W0mEditFieldLabel     matlab.ui.control.Label
        LambdanmEditField     matlab.ui.control.NumericEditField
        LambdanmLabel         matlab.ui.control.Label
        slider_z              matlab.ui.control.Slider
        ZmLabel               matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        intensity_trans_plot  matlab.ui.control.UIAxes
        intensity_rad_plot    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        lambda = 1000e-9; 
        w0 = 1e-3;
        z = 0;
    end
    
    
    methods (Access = private)
        
         function plot_gb_rad(app)
            [x,y,I] = calc_intensity(app.z,app.lambda,app.w0);
            imagesc(app.intensity_rad_plot,x,y,I);
            %imagesc(app.intensity_rad_plot, x, y, I);
            axis(app.intensity_rad_plot, 'equal');
            axis(app.intensity_rad_plot, 'tight');
            xlabel(app.intensity_rad_plot, 'x (m)');
            ylabel(app.intensity_rad_plot, 'y (m)');
            title(app.intensity_rad_plot, ['Gaussian Beam Intensity at z = ', num2str(app.z), ' m']);
            cb = colorbar(app.intensity_rad_plot);
            cb.Label.String = 'Intensity (W/m^2)';   % <-- Add your label here
            colormap(app.intensity_rad_plot, 'jet')
         end

         function plot_gb_trans(app)
            [z_,y,I] = calc_intensity_transvers(app.lambda,app.w0);
            imagesc(app.intensity_trans_plot, z_, y, I');
            axis(app.intensity_trans_plot,'xy'); % so z increases to right and y increases upwards
            %axis(app.intensity_trans_plot, 'equal');
            axis(app.intensity_trans_plot, 'tight');
            xlabel(app.intensity_trans_plot,'z (m)');
            ylabel(app.intensity_trans_plot,'y (m)');
            title(app.intensity_trans_plot,'Gaussian beam intensity (y-z plane)');
            colorbar(app.intensity_trans_plot);
            colormap(app.intensity_trans_plot,'jet');
            cb = colorbar(app.intensity_trans_plot);
            cb.Label.String = 'Intensity (W/m^2)';   % <-- Add your label here
            colormap(app.intensity_trans_plot, 'jet')
        end
        
        function updateBeamSummaryTable(app)
            zR = pi*app.w0^2 / app.lambda;
            theta = app.lambda / (pi*app.w0);
            w = app.w0 * sqrt(1 + (app.z/zR)^2);
        
            summaryData = {
                'Beam waist w0', sprintf('%.3f mm', app.w0*1e3);
                'Rayleigh range zR', sprintf('%.2f cm', zR*1e2);
                'Divergence θ', sprintf('%.2f deg', rad2deg(theta));
                'Current beam radius', sprintf('%.3f mm', w*1e3);
            };
            
            app.BeamSummaryTable.ColumnName = {};   % No headers
            app.BeamSummaryTable.Data = summaryData;
            

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.plot_gb_rad();
            app.plot_gb_trans();
            app.updateBeamSummaryTable();
        end

        % Value changing function: slider_z
        function slider_zValueChanging2(app, event)
            changingValue = event.Value;
            app.z = changingValue;
            app.plot_gb_rad();
        end

        % Value changed function: LambdanmEditField
        function LambdanmEditFieldValueChanged(app, event)
            value = app.LambdanmEditField.Value;
            app.lambda = value * 1e-9;
            app.plot_gb_rad();
            app.plot_gb_trans();
        end

        % Value changed function: W0mEditField
        function W0mEditFieldValueChanged(app, event)
            value = app.W0mEditField.Value;
            app.w0 = value;
            app.plot_gb_rad();
            app.plot_gb_trans();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ZmLabel = uilabel(app.LeftPanel);
            app.ZmLabel.Text = 'Z(m)';
            app.slider_z = uislider(app.LeftPanel);
            app.slider_z.ValueChangingFcn = createCallbackFcn(app, @slider_zValueChanging2, true);
            app.slider_z.Step = 2;
            app.LambdanmLabel = uilabel(app.LeftPanel);
            app.LambdanmLabel.Text = 'Lambda(nm)';
            app.LambdanmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LambdanmEditField.ValueChangedFcn = createCallbackFcn(app, @LambdanmEditFieldValueChanged, true);
            app.W0mEditFieldLabel = uilabel(app.LeftPanel);
            app.W0mEditFieldLabel.Text = 'W0(m)';
            app.W0mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.W0mEditField.ValueChangedFcn = createCallbackFcn(app, @W0mEditFieldValueChanged, true);
            app.BeamSummaryTable = uitable(app.LeftPanel);
            app.BeamSummaryTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.BeamSummaryTable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.intensity_rad_plot = uiaxes(app.RightPanel);
            title(app.intensity_rad_plot, 'Title')
            xlabel(app.intensity_rad_plot, 'X')
            ylabel(app.intensity_rad_plot, 'Y')
            zlabel(app.intensity_rad_plot, 'Z')
            app.intensity_trans_plot = uiaxes(app.RightPanel);
            title(app.intensity_trans_plot, 'Title')
            xlabel(app.intensity_trans_plot, 'X')
            ylabel(app.intensity_trans_plot, 'Y')
            zlabel(app.intensity_trans_plot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gausian_beam_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end